classdef Covid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        DataToPlotButtonGroup        matlab.ui.container.ButtonGroup
        CasesButton                  matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        CumulativeButton             matlab.ui.control.RadioButton
        DailyButton                  matlab.ui.control.RadioButton
        AveragedNoofDaysSliderLabel  matlab.ui.control.Label
        AveragedNoofDaysSlider       matlab.ui.control.Slider
        CountryListBoxLabel          matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        StateListBoxLabel            matlab.ui.control.Label
        StateListBox                 matlab.ui.control.ListBox
    end

    % Properties to store values for the app
    properties (Access = private)
        AveragingWindow             % Width of averaging window in days
        Countries                   % All the countries with repetition
        CountriesNames              % All countries name without repetition
        Country                     % Store the selected country
        CountryIndex                % Index of selected country to fetch data
        DataCumulative              % Stores data for cumulative number of cases and deaths
        DataDaily                   % Stores data for daily number of cases and deaths
        DataPlot                    % Stores the Data to be Plotted
        Dates                       % Stores the Dates in the mat file
        ShowBoth                    % Plot cases and deaths
        ShowCases                   % Plot cases only
        ShowCumulative              % Plot Cumulative data
        ShowDaily                   % Plot Daily data
        ShowDeaths                  % Plot Deaths only
        State                       % Stores the select state (if any)
        StateIndex                  % Index of selected state to fetch data
        States                      % All the States for a given Country
    end

    methods (Access = private)
        function PlotData(app)      % Plots the Data according to parameters
            if app.ShowCumulative
                plottype = "Cumulative";
                app.DataPlot = app.DataCumulative;
            elseif app.ShowDaily
                plottype = "Daily";
                app.DataPlot = app.DataDaily;
            end

            if string(app.State) == "All"
                name = string(app.Country);
            else
                name = string(app.State);
            end

            if app.AveragingWindow ~= 1
                app.DataPlot = movmean(app.DataPlot,[app.AveragingWindow,0]);
                name = name + " (" + string(app.AveragingWindow) + " - day mean)";
            end

            if app.ShowBoth
                cla(app.UIAxes,'reset')
                title(app.UIAxes,plottype + " Number of Cases and Deaths: " + name)
                yyaxis(app.UIAxes,'left')
                bar(app.UIAxes,app.DataPlot(:,app.StateIndex,1));
                yyaxis(app.UIAxes,'right')
                plot(app.UIAxes,app.DataPlot(:,app.StateIndex,2));
            elseif app.ShowCases
                cla(app.UIAxes,'reset')
                title(app.UIAxes,plottype + " Number of Cases: " + name)
                bar(app.UIAxes,app.DataPlot(:,app.StateIndex,1));
            elseif app.ShowDeaths
                cla(app.UIAxes,'reset')
                title(app.UIAxes,plottype + " Number of Deaths: " + name)
                plot(app.UIAxes,app.DataPlot(:,app.StateIndex,2),'r');
            end
            % Seperate the dates evenly on the graph
            app.UIAxes.XTick = round(linspace(1,length(app.Dates),11));
            app.UIAxes.XTickLabel = app.Dates(app.UIAxes.XTick);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.Countries,app.CountriesNames,app.DataCumulative, ... 
                app.DataDaily,app.Dates,app.States] = FetchData();
            app.CountryListBox.Items = app.CountriesNames;
            app.ShowBoth = app.BothButton.Value;
            app.Country = 'Global';
            app.State = 'All';
            app.CountryIndex = find(string(app.Countries) == app.Country);
            app.StateListBoxLabel.Visible = 'off';
            app.StateListBox.Items = app.States(app.Country);
            app.StateListBoxLabel.Visible = 'on';
            app.StateIndex = app.CountryIndex;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.PlotData();
        end

        % Selection changed function: DataToPlotButtonGroup
        function DataToPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.PlotData();
        end

        % Value changed function: AveragedNoofDaysSlider
        function AveragedNoofDaysSliderValueChanged(app, event)
            app.AveragingWindow = round(event.Value);
            app.PlotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.Country = app.CountryListBox.Value;
            app.CountryIndex = find(string(app.Countries) == app.Country,1);
            app.StateIndex = app.CountryIndex;
            app.StateListBoxLabel.Visible = 'off';
            app.StateListBox.Items = app.States(app.Country);
            app.StateListBoxLabel.Visible = 'on';
            app.PlotData();
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.State = app.StateListBox.Value;
            index = find(string(app.States(app.Country)) == app.State);
            app.StateIndex = app.CountryIndex + index - 1;
            app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization (Data source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DataToPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DataToPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataToPlotButtonGroupSelectionChanged, true);
            app.DataToPlotButtonGroup.Title = 'Data To Plot';
            app.CasesButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedNoofDaysSliderLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AveragedNoofDaysSliderLabel.Text = 'Averaged No of Days';
            app.AveragedNoofDaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AveragedNoofDaysSlider.Limits = [1 15];
            app.AveragedNoofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedNoofDaysSliderValueChanged, true);
            app.AveragedNoofDaysSlider.Value = 1;
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StateListBoxLabel.Text = 'State:';
            app.StateListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StateListBox.Items = {};
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.StateListBox.Value = {};
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end